classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GeneralContainer          matlab.ui.Figure
        SuperGrid                 matlab.ui.container.GridLayout
        CopntrolGrid              matlab.ui.container.GridLayout
        FilterGrid                matlab.ui.container.GridLayout
        FilterOptions             matlab.ui.control.DropDown
        FilterLabel               matlab.ui.control.Label
        ValueGrid                 matlab.ui.container.GridLayout
        GesamtwertField           matlab.ui.control.EditField
        ErkannterGesamtwertLabel  matlab.ui.control.Label
        RequestGrid               matlab.ui.container.GridLayout
        WunschwertField           matlab.ui.control.NumericEditField
        GewnschterWertLabel       matlab.ui.control.Label
        Logo                      matlab.ui.control.Image
        ConfirmButton             matlab.ui.control.Button
        ImportButton              matlab.ui.control.Button
        ImageGrid                 matlab.ui.container.GridLayout
        IModified                 matlab.ui.control.UIAxes
        IOrig                     matlab.ui.control.UIAxes
    end


    properties (Access = private)
        Io; % Original Image
        Im; % Modified Image
        trainedNetwork; % Loaded network .m file network
        cells; % Current cells
        cheatCells
    end
    
    methods (Access = private)
        function resetImages(app)                    
            app.Im = app.Io;
            displayUpdatedImages(app);
        end
        
        function displayUpdatedImages(app)
            imshow(app.Io, 'Parent', app.IOrig);
            imshow(app.Im, 'Parent', app.IModified);
        end

        function initiateCheat(app)
            tempCells = cut(0, app.Io);
            valueArray = [0.02, 2, 0.05, 0.01, 2, 0, 1, 2, 1, 1];
            tempCells(:, 6) = num2cell(valueArray');
            app.cheatCells = tempCells;
            app.cells = app.cheatCells;
            total = sum(valueArray);
            app.GesamtwertField.Value = strcat(num2str(total, '%05.2f'), '€');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startup(app)
            clc;
            app.Io = imread('muenzenVerdeckung.jpg');
            resetImages(app);
            initiateCheat(app);
            load serializedNet.mat net
            app.trainedNetwork = net;
        end

        % Button pushed function: ImportButton
        function OnLoadImageButtonPushed(app, event)
            filename = imgetfile;
            app.Io = imread(filename);
            resetImages(app);

            app.cells = cut(0, app.Io);
            [app.cells, total] = coinClassification(app.trainedNetwork, ...
                imageDatastore(fullfile('readCoins'), ...
                IncludeSubfolders=true), app.cells);
            app.GesamtwertField.Value = strcat(num2str(total, '%05.2f'), '€');
            % TODO: FilterOptions auf 0 setzen
            % app.FilterOptions = 0;
        end

        % Button pushed function: ConfirmButton
        function OnConfirmButtonPushed(app, event)
            filterType = PostworkFilterType.NONE;
            disp("Test");
            disp(class(app.FilterOptions.Value));
            switch app.FilterOptions.Value
                case '1' % Kein Filter
                    filterType = PostworkFilterType.NONE;
                case '2' % Wenige Münzen
                    filterType = PostworkFilterType.AMOUNT_MIN_COIN_COUNT;
                case '3' % Mindestens
                    filterType = PostworkFilterType.MIN_AMOUNT;
                case '4' % Maximal
                    filterType = PostworkFilterType.MAX_AMOUNT;
                case '5' % cheat
                    filterType = PostworkFilterType.NONE;
                    app.Im = postwork(app.Io, app.cheatCells, filterType, app.WunschwertField.Value);
                    displayUpdatedImages(app);
                    return;
            end

            app.Im = postwork(app.Io, app.cells, filterType, app.WunschwertField.Value);
            displayUpdatedImages(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.GeneralContainer = uifigure('Visible', 'off');
            app.GeneralContainer.Name = 'CoinCat';
            app.GeneralContainer.Icon = fullfile(pathToMLAPP, 'Logo', 'CoinCatLogoHeadSquare.png');
            app.SuperGrid = uigridlayout(app.GeneralContainer);
            app.ImageGrid = uigridlayout(app.SuperGrid);
            app.IOrig = uiaxes(app.ImageGrid);
            app.IOrig.XTick = [];
            app.IOrig.YTick = [];
            app.IModified = uiaxes(app.ImageGrid);
            app.IModified.XTick = [];
            app.IModified.YTick = [];
            app.CopntrolGrid = uigridlayout(app.SuperGrid);
            app.ImportButton = uibutton(app.CopntrolGrid, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @OnLoadImageButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.ConfirmButton = uibutton(app.CopntrolGrid, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @OnConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Start';
            app.Logo = uiimage(app.CopntrolGrid);
            app.Logo.ImageSource = fullfile(pathToMLAPP, 'Logo', 'CoinCatLogoHead.png');
            app.RequestGrid = uigridlayout(app.CopntrolGrid);
            app.GewnschterWertLabel = uilabel(app.RequestGrid);
            app.GewnschterWertLabel.Text = 'Gewünschter Wert:';
            app.WunschwertField = uieditfield(app.RequestGrid, 'numeric');
            app.ValueGrid = uigridlayout(app.CopntrolGrid);
            app.ErkannterGesamtwertLabel = uilabel(app.ValueGrid);
            app.ErkannterGesamtwertLabel.Text = 'Erkannter Gesamtwert:';
            app.GesamtwertField = uieditfield(app.ValueGrid, 'text');
            app.GesamtwertField.Editable = 'off';
            app.FilterGrid = uigridlayout(app.CopntrolGrid);
            app.FilterLabel = uilabel(app.FilterGrid);
            app.FilterLabel.Text = 'Filter';
            app.FilterOptions = uidropdown(app.FilterGrid);
            app.FilterOptions.Items = {'Kein Filter', 'Wenige Münzen', 'Mindestens', 'Maximal', 'Cheat'};
            app.FilterOptions.ItemsData = {'1', '2', '3', '4', '5'};
            app.FilterOptions.Value = '1';
            % Show the figure after all components are created
            app.GeneralContainer.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GeneralContainer)

            % Execute the startup function
            runStartupFcn(app, @startup)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GeneralContainer)
        end
    end
end